classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SettingsMenu                 matlab.ui.container.Menu
        SelectDatasetMenu            matlab.ui.container.Menu
        Menu_4                       matlab.ui.container.Menu
        Menu_5                       matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        Menu_3                       matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        DisplayTab                   matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        AverageAxes                  matlab.ui.control.UIAxes
        AudioAxes                    matlab.ui.control.UIAxes
        SpectrogramAxes              matlab.ui.control.UIAxes
        Table                        matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        FilteringSettingsPanel       matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        NdetectionsLabel             matlab.ui.control.Label
        DetectionsLabel              matlab.ui.control.Label
        LabelDropDown                matlab.ui.control.DropDown
        SelectDetectionSpinner       matlab.ui.control.Spinner
        SelectDetectionSpinnerLabel  matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        AddfilterButton              matlab.ui.control.Button
        FilterDropDown               matlab.ui.control.DropDown
        ActiveFiltersPanel           matlab.ui.container.Panel
        ActiveFilterGrid             matlab.ui.container.GridLayout
        ActiveFilterUIGrid           matlab.ui.container.GridLayout
        FilterButton                 matlab.ui.control.Button
    end

    
    properties (Access = public)
        dl DataLoader
        ds = 1
        maxSpinner = 0
        features
        featureTable
        filtersMin
        filtersMax
        filtersValueText
        filterColumns
        filteredFeatures
    end
    
    methods (Access = public)
        
        function loadData(app)
            load(sprintf("store\\poi_%d.mat", app.ds));
            app.dl = DataLoader(0);
            app.dl.auxLoader(infolist, "single");
            load(sprintf("store\\features_%d.mat", app.ds));
            app.features = D;
            load(sprintf("store\\feature_table_%d.mat", app.ds));
            app.featureTable = T;
            app.filteredFeatures = app.featureTable;
            
        end
        
        function updatePlots(app, value)
            c = app.filteredFeatures(value, :);
            fid = c.FileID;
            [s, info] = app.dl.loadAuxFromFID(fid);
            t = app.dl.fb.getTime(s);
            f = app.dl.fb.fc;
            s = s(:, c.StartIndex:c.EndIndex);
            t = t(c.StartIndex:c.EndIndex);
            ax = app.SpectrogramAxes;
            imagesc(ax, t, f, log(s));
            ylabel(ax, "Frequency (Hz)");
            xlabel(ax, "Time (s)");
            ax.XLim = [min(t), max(t)];
            ax.YLim = [min(f), max(f)];
            ax.YDir = "normal";
            ax.Title.String = info.name;
            ax.Title.Interpreter = "none";
            ax.YScale = "log";
        end

        function sliderChanged(app, event)
            idx = event.Source.Layout.Row;
            v1 = app.filtersMin(idx).Value;
            v2 = app.filtersMax(idx).Value;
            if v2 < v1
                app.filtersMax(idx).Value = v1;
                v2 = v1;
            end
            app.filtersValueText(idx).Text = sprintf("%.2f-%.2f", v1, v2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ds = 1;   
            app.loadData();
            app.FilterDropDown.Items = app.featureTable.Properties.VariableNames;
            app.UITable.ColumnName = app.featureTable.Properties.VariableNames;
        end

        % Button pushed function: AddfilterButton
        function AddFilter(app, event)
            fname = string(app.FilterDropDown.Value);
            app.filterColumns = [app.filterColumns, fname];
            av = app.FilterDropDown.Items(:);
            idx = string(av) ~= fname;
            app.FilterDropDown.Items = av(idx);
            p = app.ActiveFilterUIGrid;
              
            r = numel(app.filtersMin) + 1;
            colrange = ContourFeatures.columnMinMax(app.featureTable, fname);
            
            order = -ceil(log10(colrange(2)))+3;
            colrange = round(colrange, order);
            tix = linspace(colrange(1), colrange(2), 5);
            tix = round(tix, order);
            tix(end) = colrange(2);
            s1 = uislider(p, 'ValueChangedFcn',@(kb,event) app.sliderChanged(event)); 
            s1.Layout.Row = r;
            s1.Layout.Column = 2;
            s1.Limits = colrange;
            s1.MajorTicks = tix;
            s1.MinorTicks = tix;
            app.filtersMin = [app.filtersMin, s1];
            s2 = uislider(p, 'ValueChangedFcn',@(kb,event) app.sliderChanged(event));   
            s2.Layout.Row = r;
            s2.Layout.Column = 3;
            s2.Limits = colrange;
            s2.MajorTicks = tix;
            s2.MinorTicks = tix;
            s2.Value = colrange(2);
            app.filtersMax = [app.filtersMax, s2];
            g = uigridlayout(p);
            g.Layout.Row = r;
            g.Layout.Column = 1;
            g.RowHeight = {'1x', '1x'};
            g.ColumnWidth = {'1x'};
            t = uilabel(g);
            t.Text = fname;
            t.Layout.Row = 1;
            t.Layout.Column = 1;
            t.FontSize = 11;
            t.WordWrap = true;
            t.HorizontalAlignment = 'center';
            t = uilabel(g);
            t.Text = "";
            t.Layout.Row = 2;
            t.Layout.Column = 1;
            t.FontSize = 11;
            t.WordWrap = true;
            t.HorizontalAlignment = 'center';
            app.filtersValueText = [app.filtersValueText, t];
            
        end

        % Button pushed function: FilterButton
        function Filter(app, event)
            Nf = numel(app.filtersMax);
            T = app.featureTable;
            for i = 1:Nf
                v1 = app.filtersMin(i).Value;
                v2 = app.filtersMax(i).Value;
                col = app.filterColumns(i);
                T = ContourFeatures.filter(T, col, [v1, v2]);
            end
            app.UITable.Data = T;
            app.filteredFeatures = T;
            app.NdetectionsLabel.Text = string(size(T, 1));
        end

        % Value changed function: SelectDetectionSpinner
        function SelectContour(app, event)
            value = app.SelectDetectionSpinner.Value;
            Nmax = size(app.filteredFeatures, 1);
            if value > Nmax
                app.SelectDetectionSpinner.Value = Nmax;
                value = Nmax;
            end
            if value < 1
                app.SelectDetectionSpinner.Value = 1;
                value = 1;
            end
            app.updatePlots(value);
            
        end

        % Value changed function: LabelDropDown
        function LabelContour(app, event)
            value = app.LabelDropDown.Value;
            
        end

        % Menu selected function: Menu_4
        function DS1Selected(app, event)
            app.ds = 1;
            app.loadData();
        end

        % Menu selected function: Menu_5
        function DS2Selected(app, event)
            app.ds = 2;
            app.loadData();   
        end

        % Menu selected function: SaveMenu
        function Save(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.SelectDatasetMenu = uimenu(app.SettingsMenu);
            app.SelectDatasetMenu.Text = 'Select Dataset';
            app.Menu_4 = uimenu(app.SelectDatasetMenu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @DS1Selected, true);
            app.Menu_4.Text = '1';
            app.Menu_5 = uimenu(app.SelectDatasetMenu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @DS2Selected, true);
            app.Menu_5.Text = '2';
            app.SaveMenu = uimenu(app.SettingsMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @Save, true);
            app.SaveMenu.Text = 'Save';
            app.Menu_3 = uimenu(app.UIFigure);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FilteringSettingsPanel = uipanel(app.GridLayout);
            app.FilteringSettingsPanel.Title = 'Filtering Settings';
            app.GridLayout2 = uigridlayout(app.FilteringSettingsPanel);
            app.ActiveFiltersPanel = uipanel(app.GridLayout2);
            app.ActiveFiltersPanel.Title = 'Active Filters';
            app.ActiveFilterGrid = uigridlayout(app.ActiveFiltersPanel);
            app.FilterButton = uibutton(app.ActiveFilterGrid, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @Filter, true);
            app.FilterButton.Text = 'Filter';
            app.ActiveFilterUIGrid = uigridlayout(app.ActiveFilterGrid);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.FilterDropDown = uidropdown(app.GridLayout3);
            app.FilterDropDown.Items = {};
            app.FilterDropDown.Value = {};
            app.AddfilterButton = uibutton(app.GridLayout3, 'push');
            app.AddfilterButton.ButtonPushedFcn = createCallbackFcn(app, @AddFilter, true);
            app.AddfilterButton.Text = 'Add filter';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.SelectDetectionSpinnerLabel = uilabel(app.GridLayout4);
            app.SelectDetectionSpinnerLabel.Text = 'Select Detection';
            app.SelectDetectionSpinner = uispinner(app.GridLayout4);
            app.SelectDetectionSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectContour, true);
            app.LabelDropDown = uidropdown(app.GridLayout4);
            app.LabelDropDown.Items = {'None', 'Whale', 'Humpback', 'Southern Right', 'Brydes', 'Other', 'Noise'};
            app.LabelDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelContour, true);
            app.LabelDropDown.Value = 'None';
            app.DetectionsLabel = uilabel(app.GridLayout4);
            app.DetectionsLabel.Text = 'Detections:';
            app.NdetectionsLabel = uilabel(app.GridLayout4);
            app.NdetectionsLabel.Text = 'Ndetections';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.GridLayout6 = uigridlayout(app.DisplayTab);
            app.SpectrogramAxes = uiaxes(app.GridLayout6);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'X')
            ylabel(app.SpectrogramAxes, 'Y')
            zlabel(app.SpectrogramAxes, 'Z')
            app.AudioAxes = uiaxes(app.GridLayout6);
            title(app.AudioAxes, 'Audio')
            xlabel(app.AudioAxes, 'X')
            ylabel(app.AudioAxes, 'Y')
            zlabel(app.AudioAxes, 'Z')
            app.AverageAxes = uiaxes(app.GridLayout6);
            title(app.AverageAxes, 'Average Spectrogram')
            xlabel(app.AverageAxes, 'X')
            ylabel(app.AverageAxes, 'Y')
            zlabel(app.AverageAxes, 'Z')
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.GridLayout7 = uigridlayout(app.Table);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end